classdef VentanaPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout_Principal           matlab.ui.container.GridLayout
        Container_Principal            matlab.ui.container.Panel
        TabGroup_Principal             matlab.ui.container.TabGroup
        Tab_Inicio                     matlab.ui.container.Tab
        Panel_Inicio                   matlab.ui.container.Panel
        GridLayout_Inicio              matlab.ui.container.GridLayout
        Image_Presentacion             matlab.ui.control.Image
        HTML_Texto_Presentacion        matlab.ui.control.HTML
        Button_Iniciar_Presentacion    matlab.ui.control.Button
        Button_Vermas_Presentacion     matlab.ui.control.Button
        Label_Titulo_Presentacion      matlab.ui.control.Label
        Panel_PiePagina                matlab.ui.container.Panel
        Button_VerMas                  matlab.ui.control.Button
        Label_TextoInformacion         matlab.ui.control.Label
        Tab_Teoria                     matlab.ui.container.Tab
        Panel_Teoria                   matlab.ui.container.Panel
        GridLayout_Teoria              matlab.ui.container.GridLayout
        Panel_Indice                   matlab.ui.container.Panel
        Label_FondoTitulo              matlab.ui.control.Label
        TemarioLabel_TItulo            matlab.ui.control.Label
        Tree_Temario                   matlab.ui.container.Tree
        SistemaecuacioneslinealesNode  matlab.ui.container.TreeNode
        MtodosdirectosNode             matlab.ui.container.TreeNode
        CramerNode                     matlab.ui.container.TreeNode
        EliminacindeGaussNode          matlab.ui.container.TreeNode
        GaussJordanNode                matlab.ui.container.TreeNode
        DescomposicinLUNode            matlab.ui.container.TreeNode
        MtodositerativosNode           matlab.ui.container.TreeNode
        JacobiNode                     matlab.ui.container.TreeNode
        GaussSeidelNode                matlab.ui.container.TreeNode
        GSconrelajacinNode             matlab.ui.container.TreeNode
        SistemaecuacionesnolinealesNode  matlab.ui.container.TreeNode
        MtodoNewtonRaphsonNode         matlab.ui.container.TreeNode
        Panel_Interaccion              matlab.ui.container.Panel
        Button_Ejemplografico          matlab.ui.control.Button
        Label_FondoTItulo              matlab.ui.control.Label
        Label_Interaccion              matlab.ui.control.Label
        Panel_temas                    matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        Tab_MarcoTeorico               matlab.ui.container.Tab
        Tab_Ejemplo                    matlab.ui.container.Tab
        Tab_MasInformacion             matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        Button_MarcoTeorico            matlab.ui.control.Button
        Button_Ejemplo                 matlab.ui.control.Button
        Button_MasInformacion          matlab.ui.control.Button
        Tab_Evaluacion                 matlab.ui.container.Tab
        EvaluacionLabel                matlab.ui.control.Label
        Tab_Calculadora                matlab.ui.container.Tab
        CalculadoraLabel               matlab.ui.control.Label
        Label_Titulo                   matlab.ui.control.Label
        Label_Tema                     matlab.ui.control.Label
        Panel_Principal                matlab.ui.container.Panel
        Button_Teoria                  matlab.ui.control.Button
        Button_Evaluacion              matlab.ui.control.Button
        Button_Inicio                  matlab.ui.control.Button
        Button_Calculadora             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property_Color01=[0.0588 0.1882 0.3412] % Azul Oscuro - Letras principal, Fondo alternativo
        Property_Color02=[0 0.3451 0.4784] % Azul Claro
        Property_Color03=[0 0.5333 0.5686] % Verde Agua
        Property_Color04=[0.9255 0.9255 0.9255] % Blanco Frio - Fondo principal, Letras salternativas
    end
    
    methods (Access = private)
        % Funcion que colorea los botones diferentes al que genera el
        % evento
        function Button_BarraNavegacion_ModificarColores(app,index)
            if index~=1
                app.Button_Inicio.BackgroundColor = app.Property_Color03;
                app.Button_Inicio.FontColor = app.Property_Color04;
            end
            if index~=2
                app.Button_Teoria.BackgroundColor = app.Property_Color03;
                app.Button_Teoria.FontColor = app.Property_Color04;
            end
            if index~=3
                app.Button_Evaluacion.BackgroundColor = app.Property_Color03;
                app.Button_Evaluacion.FontColor = app.Property_Color04;
            end
            if index~=4
                app.Button_Calculadora.BackgroundColor = app.Property_Color03;
                app.Button_Calculadora.FontColor = app.Property_Color04;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button_Inicio
        function ButtonPushed_Inicio(app, event)
            Button_BarraNavegacion_ModificarColores(app,1);
            app.Button_Inicio.BackgroundColor = app.Property_Color04;
            app.Button_Inicio.FontColor = app.Property_Color03;
            app.TabGroup_Principal.SelectedTab=app.Tab_Inicio;  %Seleccionar pestaña
        end

        % Button pushed function: Button_Teoria
        function ButtonPushed_Teoria(app, event)
            Button_BarraNavegacion_ModificarColores(app,2);
            app.Button_Teoria.BackgroundColor = app.Property_Color04;
            app.Button_Teoria.FontColor = app.Property_Color03;
            app.TabGroup_Principal.SelectedTab=app.Tab_Teoria;  %Seleccionar pestaña
        end

        % Button pushed function: Button_Evaluacion
        function ButtonPushed_Evaluacion(app, event)
            Button_BarraNavegacion_ModificarColores(app,3);
            app.Button_Evaluacion.BackgroundColor = app.Property_Color04;
            app.Button_Evaluacion.FontColor = app.Property_Color03;
            app.TabGroup_Principal.SelectedTab=app.Tab_Evaluacion;  %Seleccionar pestaña
        end

        % Button pushed function: Button_Calculadora
        function ButtonPushed_Calculadora(app, event)
            Button_BarraNavegacion_ModificarColores(app,4);
            app.Button_Calculadora.BackgroundColor = app.Property_Color04;
            app.Button_Calculadora.FontColor = app.Property_Color03;
            app.TabGroup_Principal.SelectedTab=app.Tab_Calculadora;  %Seleccionar pestaña
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_Principal = uigridlayout(app.UIFigure);
            app.Container_Principal = uipanel(app.GridLayout_Principal);
            app.TabGroup_Principal = uitabgroup(app.Container_Principal);
            app.Tab_Inicio = uitab(app.TabGroup_Principal);
            app.Tab_Inicio.Title = 'Inicio';
            app.Panel_Inicio = uipanel(app.Tab_Inicio);
            app.GridLayout_Inicio = uigridlayout(app.Panel_Inicio);
            app.Image_Presentacion = uiimage(app.GridLayout_Inicio);
            app.Image_Presentacion.ImageSource = 'imagenInicio.png';
            app.HTML_Texto_Presentacion = uihtml(app.GridLayout_Inicio);
            app.HTML_Texto_Presentacion.HTMLSource = '<div style="font-size: 15px;text-align: justify;font-weight:600;"><p style="margin:7%;color:#0f3057;">Bienvenido, esta aplicación tiene como objetivo poder enseñarte el sistema de ecuaciones de manera fácil, interactiva y practica, también puedes realizar operaciones grandes y complejas en nuestra calculadora, te invitamos a empezar con el manual o la teoria.</p></div>';
            app.Button_Iniciar_Presentacion = uibutton(app.GridLayout_Inicio, 'push');
            app.Button_Iniciar_Presentacion.Text = 'Iniciar';
            app.Button_Vermas_Presentacion = uibutton(app.GridLayout_Inicio, 'push');
            app.Button_Vermas_Presentacion.Text = 'Ver más';
            app.Label_Titulo_Presentacion = uilabel(app.GridLayout_Inicio);
            app.Label_Titulo_Presentacion.Text = 'PRESENTACIÓN';
            app.Panel_PiePagina = uipanel(app.Tab_Inicio);
            app.Button_VerMas = uibutton(app.Panel_PiePagina, 'push');
            app.Button_VerMas.Text = 'Quiénes somos';
            app.Label_TextoInformacion = uilabel(app.Panel_PiePagina);
            app.Label_TextoInformacion.Text = 'Información sobre el equipo de desarrollo';
            app.Tab_Teoria = uitab(app.TabGroup_Principal);
            app.Tab_Teoria.Title = 'Teoria';
            app.Panel_Teoria = uipanel(app.Tab_Teoria);
            app.GridLayout_Teoria = uigridlayout(app.Panel_Teoria);
            app.Panel_Indice = uipanel(app.GridLayout_Teoria);
            app.Label_FondoTitulo = uilabel(app.Panel_Indice);
            app.Label_FondoTitulo.Text = '';
            app.TemarioLabel_TItulo = uilabel(app.Panel_Indice);
            app.TemarioLabel_TItulo.Text = 'Temario';
            app.Tree_Temario = uitree(app.Panel_Indice);
            app.SistemaecuacioneslinealesNode = uitreenode(app.Tree_Temario);
            app.SistemaecuacioneslinealesNode.Text = 'Sistema ecuaciones lineales';
            app.MtodosdirectosNode = uitreenode(app.SistemaecuacioneslinealesNode);
            app.MtodosdirectosNode.Text = 'Métodos directos';
            app.CramerNode = uitreenode(app.MtodosdirectosNode);
            app.CramerNode.Text = 'Cramer';
            app.EliminacindeGaussNode = uitreenode(app.MtodosdirectosNode);
            app.EliminacindeGaussNode.Text = 'Eliminación de Gauss';
            app.GaussJordanNode = uitreenode(app.MtodosdirectosNode);
            app.GaussJordanNode.Text = 'Gauss-Jordan';
            app.DescomposicinLUNode = uitreenode(app.MtodosdirectosNode);
            app.DescomposicinLUNode.Text = 'Descomposición LU';
            app.MtodositerativosNode = uitreenode(app.SistemaecuacioneslinealesNode);
            app.MtodositerativosNode.Text = 'Métodos iterativos';
            app.JacobiNode = uitreenode(app.MtodositerativosNode);
            app.JacobiNode.Text = 'Jacobi';
            app.GaussSeidelNode = uitreenode(app.MtodositerativosNode);
            app.GaussSeidelNode.Text = 'Gauss-Seidel';
            app.GSconrelajacinNode = uitreenode(app.MtodositerativosNode);
            app.GSconrelajacinNode.Text = 'G-S con relajación';
            app.SistemaecuacionesnolinealesNode = uitreenode(app.Tree_Temario);
            app.SistemaecuacionesnolinealesNode.Text = 'Sistema ecuaciones no lineales';
            app.MtodoNewtonRaphsonNode = uitreenode(app.SistemaecuacionesnolinealesNode);
            app.MtodoNewtonRaphsonNode.Text = 'Método Newton-Raphson';
            app.Panel_Interaccion = uipanel(app.GridLayout_Teoria);
            app.Button_Ejemplografico = uibutton(app.Panel_Interaccion, 'push');
            app.Button_Ejemplografico.Text = 'Ejemplo grafico';
            app.Label_FondoTItulo = uilabel(app.Panel_Interaccion);
            app.Label_FondoTItulo.Text = '';
            app.Label_Interaccion = uilabel(app.Panel_Interaccion);
            app.Label_Interaccion.Text = 'Interacción';
            app.Panel_temas = uipanel(app.GridLayout_Teoria);
            app.TabGroup = uitabgroup(app.Panel_temas);
            app.Tab_MarcoTeorico = uitab(app.TabGroup);
            app.Tab_MarcoTeorico.Title = 'MarcoTeorico';
            app.Tab_Ejemplo = uitab(app.TabGroup);
            app.Tab_Ejemplo.Title = 'Ejemplo';
            app.Tab_MasInformacion = uitab(app.TabGroup);
            app.Tab_MasInformacion.Title = 'MasInformacion';
            app.Panel = uipanel(app.Panel_temas);
            app.Button_MarcoTeorico = uibutton(app.Panel, 'push');
            app.Button_MarcoTeorico.Text = 'Marco Teórico';
            app.Button_Ejemplo = uibutton(app.Panel, 'push');
            app.Button_Ejemplo.Text = 'Ejemplo';
            app.Button_MasInformacion = uibutton(app.Panel, 'push');
            app.Button_MasInformacion.Text = 'Mas información';
            app.Tab_Evaluacion = uitab(app.TabGroup_Principal);
            app.Tab_Evaluacion.Title = 'Evaluacion';
            app.EvaluacionLabel = uilabel(app.Tab_Evaluacion);
            app.EvaluacionLabel.Text = 'Evaluacion';
            app.Tab_Calculadora = uitab(app.TabGroup_Principal);
            app.Tab_Calculadora.Title = 'Calculadora';
            app.CalculadoraLabel = uilabel(app.Tab_Calculadora);
            app.CalculadoraLabel.Text = 'Calculadora';
            app.Label_Titulo = uilabel(app.Container_Principal);
            app.Label_Titulo.Text = 'SOFTWARE DE ENSEÑANZA';
            app.Label_Tema = uilabel(app.Container_Principal);
            app.Label_Tema.Text = 'Sistema de ecuaciones';
            app.Panel_Principal = uipanel(app.Container_Principal);
            app.Button_Teoria = uibutton(app.Panel_Principal, 'push');
            app.Button_Teoria.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Teoria, true);
            app.Button_Teoria.Text = 'Teoria';
            app.Button_Evaluacion = uibutton(app.Panel_Principal, 'push');
            app.Button_Evaluacion.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Evaluacion, true);
            app.Button_Evaluacion.Text = 'Evaluacion';
            app.Button_Inicio = uibutton(app.Panel_Principal, 'push');
            app.Button_Inicio.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Inicio, true);
            app.Button_Inicio.Text = 'Inicio';
            app.Button_Calculadora = uibutton(app.Panel_Principal, 'push');
            app.Button_Calculadora.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Calculadora, true);
            app.Button_Calculadora.Text = 'Calculadora';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end